classdef MFWF_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        FlightParametersTab   matlab.ui.container.Tab
        IdealSensorsCheckBox  matlab.ui.control.CheckBox
        CommandsTab           matlab.ui.container.Tab
        VisualizationTab      matlab.ui.container.Tab
        ScopeControlsPanel    matlab.ui.container.Panel
        PositionButton        matlab.ui.control.Button
        VelocityButton        matlab.ui.control.Button
        AttitudeButton        matlab.ui.control.Button
        ControllerButton      matlab.ui.control.Button
        AnimationCheckBox     matlab.ui.control.CheckBox
        Image                 matlab.ui.control.Image
        Button                matlab.ui.control.Button
    end

    
    properties (Access = private)
        %These are the app wide variables used to work between different UI
        %objects
        
        mdl = 'MFWF_harness';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Opens the top level model.
            open('MFWF-FDC\harnesses\MFWF_harness.slx');
        end

        % Value changed function: IdealSensorsCheckBox
        function IdealSensorsCheckBoxValueChanged(app, event)
            %Enables or Disables the ideal sensor in top level model
            value = app.IdealSensorsCheckBox.Value;

            %Setting input structure value to match UI control
            if value == 1
                evalin('base', ['inputs.Sensor.Ideal = 1 ;']);
            elseif value == 0
                evalin('base', ['inputs.Sensor.Ideal = 0 ;']);
            end
        end

        % Value changed function: AnimationCheckBox
        function AnimationCheckBoxValueChanged(app, event)
            %This button turns the animation on an off
            value = app.AnimationCheckBox.Value;
            
            %Setting input structure value to match UI control
            if value == 1
                evalin('base', ['inputs.Vizualization.animation = 1 ;']);
            elseif value == 0
                evalin('base', ['inputs.Vizualization.animation = 0 ;']);
            end


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           %Run the simulation
           simout = sim(app.mdl);
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            %This button opens the velocity scope from the model.
            open_system('MFWF_harness/Visualization/Velocity')
        end

        % Button pushed function: AttitudeButton
        function AttitudeButtonPushed(app, event)
            %This button opens the attitude scope from the model.
            open_system('MFWF_harness/Visualization/Attitude')
        end

        % Button pushed function: PositionButton
        function PositionButtonPushed(app, event)
            %This button opens the position scope from the model.
            open_system('MFWF_harness/Visualization/Position')
        end

        % Button pushed function: ControllerButton
        function ControllerButtonPushed(app, event)
            %This button opens the controller scope from the model.
            open_system('MFWF_harness/Visualization/Controller Motor Output')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'button.svg';
            app.Button.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'MAAE4907N - ProjectLogo.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FlightParametersTab = uitab(app.TabGroup);
            app.FlightParametersTab.Title = 'Flight Parameters';
            app.IdealSensorsCheckBox = uicheckbox(app.FlightParametersTab);
            app.IdealSensorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IdealSensorsCheckBoxValueChanged, true);
            app.IdealSensorsCheckBox.Tooltip = {'Turns the Ideal Sensors on and off. Unchecked box will turn Real Sensors on.'};
            app.IdealSensorsCheckBox.Text = {'Ideal Sensors'; ''};
            app.IdealSensorsCheckBox.Value = true;
            app.CommandsTab = uitab(app.TabGroup);
            app.CommandsTab.Title = 'Commands';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.AnimationCheckBox = uicheckbox(app.VisualizationTab);
            app.AnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimationCheckBoxValueChanged, true);
            app.AnimationCheckBox.Text = 'Animation';
            app.ScopeControlsPanel = uipanel(app.VisualizationTab);
            app.ScopeControlsPanel.Title = 'Scope Controls';
            app.ControllerButton = uibutton(app.ScopeControlsPanel, 'push');
            app.ControllerButton.ButtonPushedFcn = createCallbackFcn(app, @ControllerButtonPushed, true);
            app.ControllerButton.Text = 'Controller ';
            app.AttitudeButton = uibutton(app.ScopeControlsPanel, 'push');
            app.AttitudeButton.ButtonPushedFcn = createCallbackFcn(app, @AttitudeButtonPushed, true);
            app.AttitudeButton.Text = 'Attitude';
            app.VelocityButton = uibutton(app.ScopeControlsPanel, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Text = 'Velocity';
            app.PositionButton = uibutton(app.ScopeControlsPanel, 'push');
            app.PositionButton.ButtonPushedFcn = createCallbackFcn(app, @PositionButtonPushed, true);
            app.PositionButton.Text = 'Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MFWF_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end